classdef window_advancedsettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CancelButton                   matlab.ui.control.Button
        DoneButton                     matlab.ui.control.Button
        AdvancedSettingsLabel          matlab.ui.control.Label
        AdvancedprotocolsettingsPanel  matlab.ui.container.Panel
        mUKgLabel                      matlab.ui.control.Label
        UKgLabel                       matlab.ui.control.Label
        UmlLabel                       matlab.ui.control.Label
        insBasal                       matlab.ui.control.NumericEditField
        InsulinsolutioninfusionrateEditFieldLabel  matlab.ui.control.Label
        insBolus                       matlab.ui.control.NumericEditField
        InsulinsolutionbolusdoseEditFieldLabel  matlab.ui.control.Label
        insConc                        matlab.ui.control.NumericEditField
        InsulinconcentrationLabel      matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        dexConc                        matlab.ui.control.NumericEditField
        DextroseconcentrationLabel     matlab.ui.control.Label
        MeasurementunitsPanel          matlab.ui.container.Panel
        GlucoseconcentrationLabel      matlab.ui.control.Label
        GlucoseconcentrationDropDown   matlab.ui.control.DropDown
    end

    properties (Access = public)
        CallingApp
        protocol
        measUnit
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingapp)
            app.UIFigure.Name = 'Advanced Settings';
            app.CallingApp = callingapp;         
            app.protocol = callingapp.protocol;
            app.measUnit = callingapp.measUnit;

            app.insBasal.Value = app.protocol.insBasal;
            app.insBolus.Value = app.protocol.insBolus;
            app.insConc.Value = app.protocol.insConcentration;
            app.dexConc.Value = 100*app.protocol.dexConcentration;
            app.GlucoseconcentrationDropDown.Value = app.measUnit.BG;
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            callingApp = app.CallingApp;
            
            % Change protocol
            clampType = callingApp.protocol.clampType;
            refPlateau = callingApp.protocol.refPlateau;
            p = Protocol(clampType, refPlateau);

            p.insBasal = app.insBasal.Value;
            p.insBolus = app.insBolus.Value;
            p.insConcentration = app.insConc.Value;
            p.dexConcentration = app.dexConc.Value/100;
            
            callingApp.protocol = p;
            
            bw = callingApp.BodyweightEditField.Value;
            h = callingApp.HeightEditField.Value;
            BSA = 0.007184*(bw^0.425)*(h^0.725);
            
            callingApp.insBasal.Value = p.getInsInfusionRate(BSA);
            callingApp.insBolus.Value = p.getInsBolus(bw);
            
            % Change measurement units
            mu = MeasUnit();
            mu.BG = app.GlucoseconcentrationDropDown.Value;
            callingApp.measUnit = mu;
            
            switch mu.BG
                case 'mmol/l'
                    callingApp.target.Value = callingApp.protocol.refPlateau;
                    callingApp.target_mu.Text = 'mmol/l';
                case 'mg/dl'
                    callingApp.target.Value = 18.0186*callingApp.protocol.refPlateau;
                    callingApp.target_mu.Text = 'mg/dl';    
            end
            
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.AdvancedSettingsLabel = uilabel(app.UIFigure);
            app.AdvancedSettingsLabel.Text = 'Advanced Settings';
            app.AdvancedprotocolsettingsPanel = uipanel(app.UIFigure);
            app.AdvancedprotocolsettingsPanel.Title = 'Advanced protocol settings';
            app.mUKgLabel = uilabel(app.AdvancedprotocolsettingsPanel);
            app.mUKgLabel.Text = 'mU/Kg';
            app.UKgLabel = uilabel(app.AdvancedprotocolsettingsPanel);
            app.UKgLabel.Text = 'U/Kg';
            app.UmlLabel = uilabel(app.AdvancedprotocolsettingsPanel);
            app.UmlLabel.Text = 'U/ml';
            app.insBasal = uieditfield(app.AdvancedprotocolsettingsPanel, 'numeric');
            app.InsulinsolutioninfusionrateEditFieldLabel = uilabel(app.AdvancedprotocolsettingsPanel);
            app.InsulinsolutioninfusionrateEditFieldLabel.Text = 'Insulin solution infusion rate:';
            app.insBolus = uieditfield(app.AdvancedprotocolsettingsPanel, 'numeric');
            app.InsulinsolutionbolusdoseEditFieldLabel = uilabel(app.AdvancedprotocolsettingsPanel);
            app.InsulinsolutionbolusdoseEditFieldLabel.Text = 'Insulin solution bolus dose:';
            app.insConc = uieditfield(app.AdvancedprotocolsettingsPanel, 'numeric');
            app.InsulinconcentrationLabel = uilabel(app.AdvancedprotocolsettingsPanel);
            app.InsulinconcentrationLabel.Text = 'Insulin concentration:';
            app.Label_2 = uilabel(app.AdvancedprotocolsettingsPanel);
            app.Label_2.Text = '%';
            app.dexConc = uieditfield(app.AdvancedprotocolsettingsPanel, 'numeric');
            app.dexConc.Value = 20;
            app.DextroseconcentrationLabel = uilabel(app.AdvancedprotocolsettingsPanel);
            app.DextroseconcentrationLabel.Text = 'Dextrose concentration:';
            app.MeasurementunitsPanel = uipanel(app.UIFigure);
            app.MeasurementunitsPanel.Title = 'Measurement units';
            app.GlucoseconcentrationLabel = uilabel(app.MeasurementunitsPanel);
            app.GlucoseconcentrationLabel.Text = 'Glucose concentration:';
            app.GlucoseconcentrationDropDown = uidropdown(app.MeasurementunitsPanel);
            app.GlucoseconcentrationDropDown.Items = {'mmol/l', 'mg/dl'};
            app.GlucoseconcentrationDropDown.Value = 'mmol/l';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = window_advancedsettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end