classdef window_correction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        OperatorEditFieldLabel      matlab.ui.control.Label
        OperatorEditField           matlab.ui.control.EditField
        EnabledatacorrectionButton  matlab.ui.control.Button
        SavecorrectionsButton       matlab.ui.control.Button
        CancelButton                matlab.ui.control.Button
        AxesPlot                    matlab.ui.control.UIAxes
        CorrectdataLabel            matlab.ui.control.Label
        WrongFormat1                matlab.ui.control.Label
        ChoosesignalDropDownLabel   matlab.ui.control.Label
        ChoosesignalDropDown        matlab.ui.control.DropDown
        WrongFormat2                matlab.ui.control.Label
    end

    
    properties (Access = public)
        UITable
        CallingApp
        dataStorage
        extraDataStorage
        savingFolder
        log
        user
        timestamp
        mu
        UIaxes
    end
    
    methods (Access = private)
    
        function updateBGplot(app,mu)
            cla(app.UIaxes,'reset')
            %app.UIaxes = uiaxes(app.UIFigure);
            app.UIaxes.Position = [345,152,379,185];
            app.UIaxes.Box = 'on';
            axInnerPos = app.UIaxes.InnerPosition;  
            
            t = app.UITable.Data;
            time = t.Time;
            bg = t.Blood_Glucose;
            plot(app.UIaxes,time,bg,'-o')
            ylabel(app.UIaxes,...
                sprintf('Blood glucose [%s]',mu))
            
            innerPosDiff = axInnerPos - app.UIaxes.InnerPosition;   
            app.UIaxes.Position = ...
                app.UIaxes.Position + innerPosDiff;            
        end
        
        function updateGIRplot(app,mu)
            cla(app.UIaxes,'reset')
            %app.UIaxes = uiaxes(app.UIFigure);
            app.UIaxes.Position = [345,152,379,185];
            app.UIaxes.Box = 'on';
            axInnerPos = app.UIaxes.InnerPosition;  
             
            if isempty(app.UITable.Data)
                plot(app.UIaxes,datetime('now','Format','HH:mm:ss'),1,'w')
            else
                t = app.UITable.Data;
                time = t.Time;
                gir = t.Dextrose;   
                plot(app.UIaxes,time,gir,'-o')
            end
            ylabel(app.UIaxes,...
                sprintf('Dextrose infusion [%s]',mu))
            
            innerPosDiff = axInnerPos - app.UIaxes.InnerPosition;   
            app.UIaxes.Position = ...
                app.UIaxes.Position + innerPosDiff;                     
        end
    
        function update2HGLUCplot(app,mu,t_basal,t_bolus)
            cla(app.UIaxes,'reset')
            %app.UIaxes = uiaxes(app.UIFigure);
            app.UIaxes.Position = [345,152,379,185];
            app.UIaxes.Box = 'on';
            axInnerPos = app.UIaxes.InnerPosition;  
            
            t_tmp = datetime('now','Format','HH:mm:ss'); 
            t_now = timeFromStart(t_tmp,app.CallingApp.TimeCalibration);
            
            yyaxis(app.UIaxes,'left')
            time_bolus = t_bolus.Time;
            gluc2h_bolus = t_bolus.Glucose_2H;   
            stem(app.UIaxes,time_bolus,gluc2h_bolus)
            %hold(app.AxesPlot,'on')
            ylabel(app.UIaxes,...
                sprintf('Bolus [%s]',mu))

            yyaxis(app.UIaxes,'right')   
            time_basal = t_basal.Time;
            gluc2h_basal = t_basal.Glucose_2H;   
            %plot(app.UIaxes,time_basal,gluc2h_basal)
            stairs(app.UIaxes,...
                [time_basal;time_basal(end)+5],...
                [gluc2h_basal;gluc2h_basal(end)])   
            ylabel(app.UIaxes,...
                sprintf('Infusion rate [%s/h]',mu))    
            ylim(app.UIaxes,[0 2*max(gluc2h_basal)])
                    
            innerPosDiff = axInnerPos - app.UIaxes.InnerPosition;   
            app.UIaxes.Position = ...
                app.UIaxes.Position + innerPosDiff;               
        end    
    
        function updateINSplot(app,mu,t_basal,t_bolus)
            cla(app.UIaxes,'reset')
            %app.UIaxes = uiaxes(app.UIFigure);
            app.UIaxes.Position = [345,152,379,185];
            app.UIaxes.Box = 'on';
            axInnerPos = app.UIaxes.InnerPosition;  
            
            t_tmp = datetime('now','Format','HH:mm:ss'); 
            t_now = timeFromStart(t_tmp,app.CallingApp.TimeCalibration);
            
            yyaxis(app.UIaxes,'left')
            time_bolus = t_bolus.Time;
            ins_bolus = t_bolus.Insulin;   
            stem(app.UIaxes,time_bolus,ins_bolus)
            %hold(app.AxesPlot,'on')
            ylabel(app.UIaxes,...
                sprintf('Bolus [%s]',mu))

            yyaxis(app.UIaxes,'right')   
            time_basal = t_basal.Time;
            ins_basal = t_basal.Insulin;   
            %plot(app.UIaxes,time_basal,ins_basal)
            stairs(app.UIaxes,...
                [time_basal; time_basal(end)+5],...
                [ins_basal;ins_basal(end)])
            ylabel(app.UIaxes,...
                sprintf('Infusion rate [%s/h]',mu))   
                    
            %hold(app.AxesPlot,'off')
            innerPosDiff = axInnerPos - app.UIaxes.InnerPosition;   
            app.UIaxes.Position = ...
                app.UIaxes.Position + innerPosDiff;              
        end    
    
        function updateOGplot(app,mu)
            cla(app.UIaxes,'reset')
            %app.UIaxes = uiaxes(app.UIFigure);
            app.UIaxes.Position = [345,152,379,185];
            app.UIaxes.Box = 'on';
            axInnerPos = app.UIaxes.InnerPosition;  
            
            t = app.UITable.Data;
            time = t.Time;
            og = t.Oral_Glucose;
            stem(app.UIaxes,time,og)
            ylabel(app.UIaxes,...
                sprintf('Oral glucose [%s]',mu))
                
            innerPosDiff = axInnerPos - app.UIaxes.InnerPosition;   
            app.UIaxes.Position = ...
                app.UIaxes.Position + innerPosDiff;            
        end    
        
        function updateEBGplot(app,mu)
            cla(app.UIaxes,'reset')
            %app.UIaxes = uiaxes(app.UIFigure);
            app.UIaxes.Position = [345,152,379,185];
            app.UIaxes.Box = 'on';
            axInnerPos = app.UIaxes.InnerPosition;  
            
            t = app.UITable.Data;
            time = t.Time;
            ebg = t.Extra_BG;
            if ~isempty(t)
                stem(app.UIaxes,time,ebg,'x')
            else 
                plot(app.UIaxes,0,0,'w')
            end
            
            ylabel(app.UIaxes,...
                sprintf('Extra BG measurements [%s]',mu))
                
            innerPosDiff = axInnerPos - app.UIaxes.InnerPosition;   
            app.UIaxes.Position = ...
                app.UIaxes.Position + innerPosDiff;            
        end         
    
        function save_log(app,mainapp)
            ID = mainapp.patientData.ID;
            filename = [ID,'_',app.timestamp];
            if ~exist(fullfile(app.savingFolder,'Data','Logs'), 'dir') 
                mkdir(fullfile(app.savingFolder,'Data','Logs'))
            end
            filepath = fullfile(app.savingFolder,'Data','Logs',filename);
            Log = app.log;
            User = app.user;
            save(filepath,'Log','User')
        end
        
        function TableCellEditCallback(app,~,event)
            try
                app.WrongFormat2.Visible = 'off';
                %EditData = event.EditData;
                NewData = event.NewData;
                PrevData = event.PreviousData;
                indices = event.Indices;
                Time = app.UITable.Data.Time(indices(1));
                field = app.UITable.ColumnName(2);
                T = table({field},Time,NewData,PrevData);
                app.log = [app.log; T]; 
                
                switch app.ChoosesignalDropDown.Value
                    case 'Blood glucose'
                        updateBGplot(app,app.mu)
                    case 'Dextrose'
                        updateGIRplot(app,app.mu)
                    case 'Insulin'
                        t = app.UITable.Data;
                        t_bolus = t(strcmp(t.Type,{'bolus'}),:);
                        t_basal = t(strcmp(t.Type,{'basal'}),:);
                        updateINSplot(app,app.mu,t_basal,t_bolus)
                    case '2H glucose'
                        t = app.UITable.Data;
                        t_bolus = t(strcmp(t.Type,{'bolus'}),:);
                        t_basal = t(strcmp(t.Type,{'basal'}),:);                    
                        update2HGLUCplot(app,app.mu,t_basal,t_bolus)
                    case 'Oral glucose intake'
                        updateOGplot(app,app.mu)
                    case 'Extra BG readings'
                        updateEBGplot(app,app.mu)
                end
            catch
                app.WrongFormat2.Visible = 'on';
            end
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, ds, eds, savingDir)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,'center')
            app.UIFigure.Visible = 'on';
            app.UIaxes = uiaxes(app.UIFigure);
            app.UIFigure.Name = 'Correct data';
            
            app.CallingApp = mainapp;
            app.dataStorage = ds;
            app.extraDataStorage = eds;
            app.savingFolder = savingDir;
            app.log = [];
            app.timestamp = char(datetime('now','Format','MMMdd_HHmmss'));
            
            % BG table (default) 
            app.mu = 'mmol/L';
            [bg_value,bg_time] = getBG(app.dataStorage);
            T_BG = table(bg_time,bg_value,...
                'VariableNames',{'Time','Blood_Glucose'});
            app.UITable = uitable(app.UIFigure,'Position',[30,150,270,185]);
            app.UITable.Data = T_BG;
            app.UITable.ColumnName = ...
                {'Time',sprintf('BG [%s]',app.mu)};
            
            % plot the data
            updateBGplot(app,app.mu);
            
            % assign callback
            app.UITable.CellEditCallback = @app.TableCellEditCallback;
        end

        % Button pushed function: EnabledatacorrectionButton
        function EnabledatacorrectionButtonPushed(app, event)
            if ~isempty(app.OperatorEditField.Value)
                app.WrongFormat1.Visible = 'off'; 
                app.OperatorEditField.Enable = 'off';
                app.user = app.OperatorEditField.Value;
                app.EnabledatacorrectionButton.Enable = 'off';
                %app.UITable.ColumnEditable = [false, true];
                if size(app.UITable.Data,2) == 2
                    app.UITable.ColumnEditable = [true,true];
                elseif size(app.UITable.Data,2) == 3
                    app.UITable.ColumnEditable = [true,true,false];
                end 
           else
                app.WrongFormat1.Visible = 'on'; 
           end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: SavecorrectionsButton
        function SavecorrectionsButtonPushed(app, event)
            switch app.ChoosesignalDropDown.Value
                case 'Blood glucose'
                    t = app.UITable.Data;
                    app.dataStorage.BG_time = t.Time;
                    app.dataStorage.BG_value = t.Blood_Glucose;
                    update_BGplot(app.CallingApp,app.dataStorage)
                    [~,gir_value,gir_time] = app.dataStorage.getGIR();
                    if ~isempty(gir_value)
                        update_last_points(app.CallingApp,...
                            t.Blood_Glucose(end),t.Time(end),...
                            gir_value(end),gir_time(end));
                    else 
                        update_last_points(app.CallingApp,...
                            t.Blood_Glucose(end),t.Time(end),...
                            [],[]);
                    end
                case 'Dextrose'
                    t = app.UITable.Data;
                    app.dataStorage.GIR_time = t.Time;
                    app.dataStorage.realGIR_value = t.Dextrose;
                    update_GIRplot(app.CallingApp,app.dataStorage)
                    [bg_value,bg_time] = app.dataStorage.getBG();
                    update_last_points(app.CallingApp,...
                        bg_value(end),bg_time(end),...
                        t.Dextrose(end),t.Time(end));
                case 'Insulin'
                    t = app.UITable.Data;
                    t_bolus = t(strcmp(t.Type,{'bolus'}),:);
                    t_basal = t(strcmp(t.Type,{'basal'}),:);
                    app.extraDataStorage.insulin.bolus.time = ...
                        t_bolus.Time;
                    app.extraDataStorage.insulin.infusionRateChange.time = ...
                        t_basal.Time;
                    app.extraDataStorage.insulin.bolus.value = ...
                        t_bolus.Insulin;
                    app.extraDataStorage.insulin.infusionRateChange.value = ...
                        t_basal.Insulin;                
                case '2H glucose'
                    t = app.UITable.Data;
                    t_bolus = t(strcmp(t.Type,{'bolus'}),:);
                    t_basal = t(strcmp(t.Type,{'basal'}),:);
                    app.extraDataStorage.glucose2H.bolus.time = ...
                        t_bolus.Time;
                    app.extraDataStorage.glucose2H.infusionRateChange.time = ...
                        t_basal.Time;
                    app.extraDataStorage.glucose2H.bolus.value = ...
                        t_bolus.Glucose_2H;
                    app.extraDataStorage.glucose2H.infusionRateChange.value = ...
                        t_basal.Glucose_2H;                       
                case 'Oral glucose intake'
                    t = app.UITable.Data;
                    app.extraDataStorage.oralGlucose.time = ...
                        t.Time;
                    app.extraDataStorage.oralGlucose.value = ...
                        t.Oral_glucose;     
                case 'Extra BG readings'
                    t = app.UITable.Data;
                    app.extraDataStorage.extra_BG.time = t.time;
                    app.extraDataStorage.extra_BG.value = t.Extra_BG;
                    update_BGplot(app.CallingApp,app.dataStorage)                    
            end
            
            save_backup(app.CallingApp)
            save_log(app,app.CallingApp)
            delete(app)
        end

        % Value changed function: ChoosesignalDropDown
        function ChoosesignalDropDownValueChanged(app, event)
            value = app.ChoosesignalDropDown.Value;
            switch value
                case 'Blood glucose'
                    %cla(app.AxesPlot,'reset')
                    app.mu = 'mmol/l';
                    [bg_value,bg_time] = getBG(app.dataStorage);
                    T_BG = table(bg_time,bg_value,...
                        'VariableNames',{'Time','Blood_Glucose'});
                    app.UITable.Data = T_BG;
                    app.UITable.ColumnName = ...
                        {'Time',sprintf('BG [%s]',app.mu)};
                    updateBGplot(app,app.mu);
                    
                case 'Dextrose'
                    % da sistemare caso empty gir
                    app.mu = 'ml/h';
                    [~,gir_value,gir_time] = getGIR(app.dataStorage) ;
                    if isempty(gir_value)
                        app.UITable.Data = [];
                    else   
                        T_GIR = table(gir_time,gir_value,...
                            'VariableNames',{'Time','Dextrose'});
                        app.UITable.Data = T_GIR;
                    end
                    updateGIRplot(app,app.mu); 
                    app.UITable.ColumnName = ...
                        {'Time',sprintf('Dextrose [%s]',app.mu)};
                    
                case '2H glucose'
                    app.mu = 'ml';
                    
                    % bolus
                    gluc2h_bolus_value = ...
                        app.extraDataStorage.glucose2H.bolus.value;
                    gluc2h_bolus_time = ...
                        app.extraDataStorage.glucose2H.bolus.time;
                    gluc2h_bolus_type = cell(length(gluc2h_bolus_time),1);
                    gluc2h_bolus_type(:) = {'bolus'};
                    T_bolus = table(gluc2h_bolus_time,gluc2h_bolus_value,...
                        gluc2h_bolus_type,'VariableNames',...
                        {'Time','Glucose_2H','Type'});
                    T_bolus = sortrows(T_bolus,{'Time'});
                    
                    % basal
                    gluc2h_basal_value = ...
                        app.extraDataStorage.glucose2H.infusionRateChange.value;
                    gluc2h_basal_time = ...
                        app.extraDataStorage.glucose2H.infusionRateChange.time;
                    gluc2h_basal_type = cell(length(gluc2h_basal_time),1);
                    gluc2h_basal_type(:) = {'basal'};
                    T_basal = table(gluc2h_basal_time,gluc2h_basal_value,...
                        gluc2h_basal_type,'VariableNames',...
                        {'Time','Glucose_2H','Type'});
                    T_basal = sortrows(T_basal,{'Time'});
                        
                    T_2HGLUC = [T_bolus;T_basal];
                    T_2HGLUC = sortrows(T_2HGLUC,{'Time'});
                    app.UITable.Data = T_2HGLUC;
                    app.UITable.ColumnName = ...
                        {'Time',sprintf('2H glucose [%s]',app.mu),'Type'};
                    update2HGLUCplot(app,app.mu,T_basal,T_bolus);
                    
                case 'Insulin'
                    app.mu = 'ml';
                    
                    % bolus
                    ins_bolus_value = ...
                        app.extraDataStorage.insulin.bolus.value;
                    ins_bolus_time = ...
                        app.extraDataStorage.insulin.bolus.time;
                    ins_bolus_type = cell(length(ins_bolus_time),1);
                    ins_bolus_type(:) = {'bolus'};
                    T_bolus = table(ins_bolus_time,ins_bolus_value,...
                        ins_bolus_type,'VariableNames',...
                        {'Time','Insulin','Type'});
                    T_bolus = sortrows(T_bolus,{'Time'});
                    
                    % basal
                    ins_basal_value = ...
                        app.extraDataStorage.insulin.infusionRateChange.value;
                    ins_basal_time = ...
                        app.extraDataStorage.insulin.infusionRateChange.time;
                    ins_basal_type = cell(length(ins_basal_time),1);
                    ins_basal_type(:) = {'basal'};
                    T_basal = table(ins_basal_time,ins_basal_value,...
                        ins_basal_type,'VariableNames',...
                        {'Time','Insulin','Type'});
                    T_basal = sortrows(T_basal,{'Time'});
                        
                    T_INS = [T_bolus;T_basal];
                    T_INS = sortrows(T_INS,{'Time'});
                    app.UITable.Data = T_INS;
                    app.UITable.ColumnName = ...
                        {'Time',sprintf('Insulin [%s]',app.mu),'Type'};
                    updateINSplot(app,app.mu,T_basal,T_bolus);
                    
                case 'Oral glucose intake'
                    app.mu = 'g';
                    oralGluc_time = app.extraDataStorage.oralGlucose.time;
                    oralGluc_value = app.extraDataStorage.oralGlucose.value;
                    T_OG = table(oralGluc_time,oralGluc_value,...
                        'VariableNames',{'Time','Oral_Glucose'});
                    app.UITable.Data = T_OG;
                    app.UITable.ColumnName = ...
                        {'Time',sprintf('Oral glucose [%s]',app.mu)};
                    updateOGplot(app,app.mu);
                    
                case 'Extra BG readings'
                    app.mu = 'mmol/L';
                    extraBG_time = app.extraDataStorage.extra_BG.time;
                    extraBG_value = app.extraDataStorage.extra_BG.value;
                    T_EBG = table(extraBG_time,extraBG_value,...
                        'VariableNames',{'Time','Extra_BG'});
                    app.UITable.Data = T_EBG;
                    app.UITable.ColumnName = ...
                        {'Time',sprintf('Extra BG [%s]',app.mu)};
                    updateEBGplot(app,app.mu);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.OperatorEditFieldLabel = uilabel(app.UIFigure);
            app.OperatorEditFieldLabel.Text = 'Operator:';
            app.OperatorEditField = uieditfield(app.UIFigure, 'text');
            app.EnabledatacorrectionButton = uibutton(app.UIFigure, 'push');
            app.EnabledatacorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @EnabledatacorrectionButtonPushed, true);
            app.EnabledatacorrectionButton.Text = 'Enable data correction';
            app.SavecorrectionsButton = uibutton(app.UIFigure, 'push');
            app.SavecorrectionsButton.ButtonPushedFcn = createCallbackFcn(app, @SavecorrectionsButtonPushed, true);
            app.SavecorrectionsButton.Text = 'Save corrections';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.AxesPlot = uiaxes(app.UIFigure);
            title(app.AxesPlot, '')
            ylabel(app.AxesPlot, 'Blood glucose [mg/dL]')
            app.AxesPlot.Visible = 'off';
            app.CorrectdataLabel = uilabel(app.UIFigure);
            app.CorrectdataLabel.Text = 'Correct data';
            app.WrongFormat1 = uilabel(app.UIFigure);
            app.WrongFormat1.Visible = 'off';
            app.WrongFormat1.Text = 'An operator name must me specified.';
            app.ChoosesignalDropDownLabel = uilabel(app.UIFigure);
            app.ChoosesignalDropDownLabel.Text = 'Choose signal:';
            app.ChoosesignalDropDown = uidropdown(app.UIFigure);
            app.ChoosesignalDropDown.Items = {'Blood glucose', 'Dextrose', '2H glucose', 'Insulin', 'Oral glucose intake', 'Extra BG readings'};
            app.ChoosesignalDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoosesignalDropDownValueChanged, true);
            app.ChoosesignalDropDown.Value = 'Blood glucose';
            app.WrongFormat2 = uilabel(app.UIFigure);
            app.WrongFormat2.Visible = 'off';
            app.WrongFormat2.Text = 'Wrong format! Time format is HH:mm:ss.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = window_correction(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end