classdef window_popup_control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SaveButton                 matlab.ui.control.Button
        InsertnewdatumLabel        matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        BloodglucosereadingsPanel  matlab.ui.container.Panel
        ValueLabel_2               matlab.ui.control.Label
        BG_value                   matlab.ui.control.NumericEditField
        BG_measUnit_label          matlab.ui.control.Label
        BG_time                    matlab.ui.control.EditField
        Now_BG                     matlab.ui.control.Button
        WrongFormat1               matlab.ui.control.Label
        CheckButton                matlab.ui.control.Button
        Confirm_BG                 matlab.ui.control.Button
        realGIR_measUnit_label_2   matlab.ui.control.Label
        WrongFormat4               matlab.ui.control.Label
        DextroseinfusionPanel      matlab.ui.container.Panel
        SuggestedLabel             matlab.ui.control.Label
        Suggested_GIR              matlab.ui.control.NumericEditField
        suggGIR_measUnit_label     matlab.ui.control.Label
        ActualinfusionLabel        matlab.ui.control.Label
        GIR_value                  matlab.ui.control.NumericEditField
        realGIR_measUnit_label     matlab.ui.control.Label
        GIR_time                   matlab.ui.control.EditField
        realGIR_measUnit_label_3   matlab.ui.control.Label
        Now_GIR                    matlab.ui.control.Button
        Confirm_GIR                matlab.ui.control.Button
        WrongFormat2               matlab.ui.control.Label
        suggGIR_measUnit_label_2   matlab.ui.control.Label
        equivalentdoseLabel        matlab.ui.control.Label
        Suggested_GIR_2            matlab.ui.control.NumericEditField
        WrongFormat3               matlab.ui.control.Label
    end

    
    properties (Access = public)
        ds
        mu
        mainTimer
        timer5
        timer10
        conversionFactorBG
        conversionFactorGIR_1
        conversionFactorGIR_2
        hstar
        hline
        control_parameters
        reference
        %StartTime
        Ie
    end
    
    properties (Access = private)
        CallingApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, period)
            app.UIFigure.Name = 'Reading';
            app.CallingApp = mainapp;
            app.ds = mainapp.dataStorage;
            app.mu = mainapp.measUnit;
            app.hstar = [];
            app.hline = [];
            app.control_parameters = mainapp.control_parameters;
            
            app.BG_measUnit_label.Text = app.mu.BG;
            app.suggGIR_measUnit_label.Text = app.mu.GIR;
            app.realGIR_measUnit_label.Text = app.mu.GIR;
            
            app.conversionFactorBG = 18.0182;           % mg/dL -> mmol/L
            c = mainapp.protocol.dexConcentration; 
            app.conversionFactorGIR_1 = 60/(1000*c);    % mg/min -> mL/h         
            app.conversionFactorGIR_2 = c/(60/5);       % mL/h -> g/5min

            % timer for closing
            app.BG_measUnit_label.Text = app.mu.BG;
            app.mainTimer = timer;
            app.mainTimer.StartDelay = period-5;
            app.mainTimer.TimerFcn = @(myTimerObj, thisEvent)delete(app);            
            
            % timer countdown -10
            app.timer10 = timer;
            app.timer10.UserData.mainapp = app;
            app.timer10.StartDelay = period-15;
            app.timer10.UserData.countdown = 10;
            app.timer10.TimerFcn = @labelfun;
            
            % timer countdown -5
            app.timer5 = timer;
            app.timer5.UserData.mainapp = app;
            app.timer5.StartDelay = period-10;
            app.timer5.UserData.countdown = 5;            
            app.timer5.TimerFcn = @labelfun;            
     
            start(app.mainTimer)
            start(app.timer10)
            start(app.timer5)
            
        end

        % Button pushed function: Now_BG
        function Now_BGButtonPushed(app, event)
            t_tmp = datetime('now','Format','HH:mm:ss');
            app.BG_time.Value = num2str(timeFromStart(t_tmp, app.CallingApp.TimeCalibration));
        end

        % Button pushed function: Now_GIR
        function Now_GIRButtonPushed(app, event)
            t_tmp = datetime('now','Format','HH:mm:ss');
            app.GIR_time.Value = num2str(timeFromStart(t_tmp, app.CallingApp.TimeCalibration));
        end

        % Button pushed function: Confirm_BG
        function Confirm_BGButtonPushed(app, event)
            try             
                % gain
                switch app.mu.BG
                    case 'mmol/l'
                        gain = 1;
                    case 'mg/dl'
                        gain = 1/app.conversionFactorBG;
                end
                
                app.WrongFormat1.Visible = 'off'; 
                app.WrongFormat3.Visible = 'off';
                app.WrongFormat4.Visible = 'off';
                                
                % Current BG measurement
                y = app.BG_value.Value*gain;                         % mmol/l
                t_now = str2double(app.BG_time.Value);
                
                if floor(t_now)~=t_now
                    error('popup0:WrongTimeFormat','Time must be an integer.')
                end
                                
                % Previous BG measurement (for derivative)
                [bgValue_vect, bgTime_vect] = app.ds.getBG(); 
                if ~isempty(bgValue_vect)
                    y_old = bgValue_vect(end)*gain;                  % mmol/l
                    t_old = bgTime_vect(end);
                else   % default BG value (if previous measurements are missing)
                    y_old = 4.5;                                     % mmol/L
                    t_old = t_now-minutes(5);
                end
                % Warning: samples too close
                if (t_now-t_old)<2
                    app.WrongFormat4.Visible = 'on';  
                    app.WrongFormat4.Text = 'Samples are very close';
                end
                
                delta_t = t_now - app.CallingApp.StartControlTime;
                app.reference = getReference(delta_t, app.CallingApp);
                
                % Convert measurement units
                y = y*app.conversionFactorBG;                       % mg/dL
                ref = app.reference*app.conversionFactorBG;         % mg/dL
                y_old = y_old*app.conversionFactorBG;               % mg/dL
                
                % Load control parameters
                cp = app.control_parameters;    
                
                %% ---- PID ---- %%
                Ie_old = app.ds.Ie;                                 % mg/dL
                
                output = myPID2(ref, y, Ie_old, y_old, t_now, t_old, cp);    
                
                u = output(1);                                      % mg/min                 
                app.Ie = output(2);                                 % mg/dL
                %% ------------- %%
                
                
                % Saturation
                u = u*app.conversionFactorGIR_1;                    % mL/h
                u = round(u,1);
                
                app.Suggested_GIR.Value = u;    
                app.GIR_value.Value = u;
                app.Suggested_GIR_2.Value = u*app.conversionFactorGIR_2;  
                
                app.GIR_value.Editable = 'on';
                app.GIR_time.Editable = 'on';
                app.Now_GIR.Enable = 'on';   
                app.Confirm_GIR.Enable = 'on';

            catch ME
                app.GIR_value.Editable = 'off';
                app.GIR_time.Editable = 'off';
                app.Now_GIR.Enable = 'off';   
                app.Confirm_GIR.Enable = 'off';
                app.WrongFormat3.Visible = 'on';  
                app.WrongFormat3.Text = ME.message;
            end
        end

        % Button pushed function: Confirm_GIR
        function Confirm_GIRButtonPushed(app, event)
            try
                app.WrongFormat3.Visible = 'off';
                app.ds.Ie = app.Ie;
                app.WrongFormat2.Visible = 'off';
                bgValue_tmp = app.BG_value.Value;
                bgTime_tmp = app.BG_time.Value;
                bgTime_tmp = str2double(bgTime_tmp);
                if floor(bgTime_tmp)~=bgTime_tmp
                    error('popup:WrongTimeFormat','Correct time of reading.')
                end
                [bgValue_vect,bgTime_vect]=app.ds.getBG();
                app.ds.BG_value=[bgValue_vect;bgValue_tmp];
                app.ds.BG_time=[bgTime_vect;bgTime_tmp];
                
                sugg_girValue_tmp = app.Suggested_GIR.Value;
                act_girValue_tmp = app.GIR_value.Value;
                act_girTime_tmp = app.GIR_time.Value;
                act_girTime_tmp = str2double(act_girTime_tmp);     
                if floor(act_girTime_tmp)~=act_girTime_tmp
                    error('popup:WrongTimeFormat','Time must be an integer.')
                end
                [sugg_girValue_vect,act_girValue_vect,...
                    act_girTime_vect]=app.ds.getGIR();
                app.ds.suggGIR_value=[sugg_girValue_vect;sugg_girValue_tmp];
                app.ds.realGIR_value=[act_girValue_vect;act_girValue_tmp];
                app.ds.GIR_time=[act_girTime_vect;act_girTime_tmp];                
                
                update_BGplot(app.CallingApp,app.ds)
                update_GIRplot(app.CallingApp,app.ds)
                update_last_points(app.CallingApp,bgValue_tmp,bgTime_tmp,...
                    act_girValue_tmp,act_girTime_tmp)
                
                save_backup(app.CallingApp)
                
                stop(app.timer10)
                stop(app.timer5)
                stop(app.mainTimer)
                delete(app);
            catch ME
                app.WrongFormat3.Text = ME.message;
                app.WrongFormat3.FontColor = 'red';
                app.WrongFormat3.Visible = 'on';
            end
        end

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)
            try
                app.WrongFormat1.Visible = 'off'; 
                callingApp = app.CallingApp;
                value = app.BG_value.Value;
                time = str2double(app.BG_time.Value);
                if floor(time)~=time
                    error('popup:WrongTimeFormat','Time must be an integer.')
                end
                delete(app.hstar)
                delete(app.hline)
                [app.hstar, app.hline] = update_BGplot(callingApp, callingApp.dataStorage, time, value);
            catch ME
                app.WrongFormat1.Visible = 'on';        
                app.WrongFormat1.Text = ME.message;     
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.mainTimer) & isvalid(app.mainTimer)
                stop(app.mainTimer)
            end
            if ~isempty(app.timer10) & isvalid(app.mainTimer)
                stop(app.timer10)
            end
            if ~isempty(app.timer5) & isvalid(app.mainTimer)
                stop(app.timer5)
            end
            delete(app.hstar)
            delete(app.hline)
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.Enable = 'off';
            app.SaveButton.Visible = 'off';
            app.SaveButton.Text = 'Save';
            app.InsertnewdatumLabel = uilabel(app.UIFigure);
            app.InsertnewdatumLabel.Text = 'Insert new datum';
            app.Label = uilabel(app.UIFigure);
            app.Label.Visible = 'off';
            app.BloodglucosereadingsPanel = uipanel(app.UIFigure);
            app.BloodglucosereadingsPanel.Title = 'Blood glucose readings';
            app.ValueLabel_2 = uilabel(app.BloodglucosereadingsPanel);
            app.ValueLabel_2.Text = 'Value:';
            app.BG_value = uieditfield(app.BloodglucosereadingsPanel, 'numeric');
            app.BG_value.Tooltip = {'Insert numeric value'};
            app.BG_measUnit_label = uilabel(app.BloodglucosereadingsPanel);
            app.BG_measUnit_label.Text = 'mg/dL';
            app.BG_time = uieditfield(app.BloodglucosereadingsPanel, 'text');
            app.BG_time.Tooltip = {'Minutes'};
            app.Now_BG = uibutton(app.BloodglucosereadingsPanel, 'push');
            app.Now_BG.ButtonPushedFcn = createCallbackFcn(app, @Now_BGButtonPushed, true);
            app.Now_BG.Text = 'now';
            app.WrongFormat1 = uilabel(app.BloodglucosereadingsPanel);
            app.WrongFormat1.Visible = 'off';
            app.WrongFormat1.Text = 'Time format is hh:mm:ss.';
            app.CheckButton = uibutton(app.BloodglucosereadingsPanel, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Text = 'Check';
            app.Confirm_BG = uibutton(app.BloodglucosereadingsPanel, 'push');
            app.Confirm_BG.ButtonPushedFcn = createCallbackFcn(app, @Confirm_BGButtonPushed, true);
            app.Confirm_BG.Text = 'Confirm';
            app.realGIR_measUnit_label_2 = uilabel(app.BloodglucosereadingsPanel);
            app.realGIR_measUnit_label_2.Text = 'Time:';
            app.WrongFormat4 = uilabel(app.BloodglucosereadingsPanel);
            app.WrongFormat4.Visible = 'off';
            app.WrongFormat4.Text = 'Label3';
            app.DextroseinfusionPanel = uipanel(app.UIFigure);
            app.DextroseinfusionPanel.Title = 'Dextrose infusion';
            app.SuggestedLabel = uilabel(app.DextroseinfusionPanel);
            app.SuggestedLabel.Text = 'Suggested:';
            app.Suggested_GIR = uieditfield(app.DextroseinfusionPanel, 'numeric');
            app.Suggested_GIR.Editable = 'off';
            app.suggGIR_measUnit_label = uilabel(app.DextroseinfusionPanel);
            app.suggGIR_measUnit_label.Text = 'mg/dL';
            app.ActualinfusionLabel = uilabel(app.DextroseinfusionPanel);
            app.ActualinfusionLabel.Text = 'Actual infusion:';
            app.GIR_value = uieditfield(app.DextroseinfusionPanel, 'numeric');
            app.GIR_value.Editable = 'off';
            app.GIR_value.Tooltip = {'Insert numeric value'};
            app.realGIR_measUnit_label = uilabel(app.DextroseinfusionPanel);
            app.realGIR_measUnit_label.Text = 'mg/dL';
            app.GIR_time = uieditfield(app.DextroseinfusionPanel, 'text');
            app.GIR_time.Editable = 'off';
            app.GIR_time.Tooltip = {'Minutes'};
            app.realGIR_measUnit_label_3 = uilabel(app.DextroseinfusionPanel);
            app.realGIR_measUnit_label_3.Text = 'Infusion time:';
            app.Now_GIR = uibutton(app.DextroseinfusionPanel, 'push');
            app.Now_GIR.ButtonPushedFcn = createCallbackFcn(app, @Now_GIRButtonPushed, true);
            app.Now_GIR.Enable = 'off';
            app.Now_GIR.Text = 'now';
            app.Confirm_GIR = uibutton(app.DextroseinfusionPanel, 'push');
            app.Confirm_GIR.ButtonPushedFcn = createCallbackFcn(app, @Confirm_GIRButtonPushed, true);
            app.Confirm_GIR.Enable = 'off';
            app.Confirm_GIR.Text = 'Confirm';
            app.WrongFormat2 = uilabel(app.DextroseinfusionPanel);
            app.WrongFormat2.Visible = 'off';
            app.WrongFormat2.Text = 'Time format is hh:mm:ss.';
            app.suggGIR_measUnit_label_2 = uilabel(app.DextroseinfusionPanel);
            app.suggGIR_measUnit_label_2.Text = 'g/5min';
            app.equivalentdoseLabel = uilabel(app.DextroseinfusionPanel);
            app.equivalentdoseLabel.Text = '    equivalent dose:';
            app.Suggested_GIR_2 = uieditfield(app.DextroseinfusionPanel, 'numeric');
            app.Suggested_GIR_2.Editable = 'off';
            app.WrongFormat3 = uilabel(app.UIFigure);
            app.WrongFormat3.Visible = 'off';
            app.WrongFormat3.Text = 'Label2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = window_popup_control(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end