classdef window_popup_reading < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Label                     matlab.ui.control.Label
        BloodglucosereadingPanel  matlab.ui.container.Panel
        CheckButton               matlab.ui.control.Button
        WrongFormat1              matlab.ui.control.Label
        Confirm_BG                matlab.ui.control.Button
        Now_BG                    matlab.ui.control.Button
        BG_measUnit_label_2       matlab.ui.control.Label
        BG_time                   matlab.ui.control.EditField
        BG_measUnit_label         matlab.ui.control.Label
        BG_value                  matlab.ui.control.NumericEditField
        ValueLabel_2              matlab.ui.control.Label
        InsertnewdatumLabel       matlab.ui.control.Label
        CancelButton              matlab.ui.control.Button
        SaveButton                matlab.ui.control.Button
    end

    
    properties (Access = public)
        ds
        mu
        mainTimer
        timer10
        timer5
        hstar
        hline
    end
    
    properties (Access = private)
        CallingApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, period)
            app.UIFigure.Name = 'Reading';
            app.CallingApp = mainapp;
            app.ds = mainapp.dataStorage;
            app.mu = mainapp.measUnit;
            app.hstar = [];
            app.hline = [];
            
            % timer for closing
            app.BG_measUnit_label.Text = app.mu.BG;
            app.mainTimer = timer;
            app.mainTimer.StartDelay = period-5;
            app.mainTimer.TimerFcn = @(myTimerObj, thisEvent)delete(app);
            
            % timer countdown -10
            app.timer10 = timer;
            app.timer10.UserData.mainapp = app;
            app.timer10.StartDelay = period-15;
            app.timer10.UserData.countdown = 10;
            app.timer10.TimerFcn = @labelfun;
            
            % timer countdown -5
            app.timer5 = timer;
            app.timer5.UserData.mainapp = app;
            app.timer5.StartDelay = period-10;
            app.timer5.UserData.countdown = 5;            
            app.timer5.TimerFcn = @labelfun;            

            start(app.mainTimer)
            start(app.timer10)
            start(app.timer5)
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: Now_BG
        function Now_BGButtonPushed(app, event)
            t_tmp = datetime('now','Format','HH:mm:ss');
            app.BG_time.Value = num2str(timeFromStart(t_tmp, app.CallingApp.TimeCalibration));
        end

        % Button pushed function: Confirm_BG
        function Confirm_BGButtonPushed(app, event)
            try
                bgTime_tmp = str2double(app.BG_time.Value);
                if floor(bgTime_tmp)~=bgTime_tmp
                    error('popup0:WrongTimeFormat','Time must be an integer.')
                end
                
                bgValue_tmp = app.BG_value.Value;
                [bgValue_vect, bgTime_vect] = app.ds.getBG();

                if ~isempty(bgTime_vect) 
                    if bgTime_vect(end)>=bgTime_tmp
                        error('popup0:WrongTime','Current measurement appears to be\n acquired before the previous one.')
                    end
                end

                app.ds.BG_value = [bgValue_vect; bgValue_tmp];
                app.ds.BG_time = [bgTime_vect; bgTime_tmp];
                
                app.WrongFormat1.Visible = 'off'; 
                app.BG_value.Editable = 'off';
                app.BG_time.Editable = 'off';
                app.Now_BG.Enable = 'off';
                app.Confirm_BG.Enable = 'off';
                
                app.SaveButton.Enable = 'on';
                
                update_BGplot(app.CallingApp, app.ds)
                update_last_points(app.CallingApp, bgValue_tmp,bgTime_tmp,0,[])
                
                save_backup(app.CallingApp)
                
                stop(app.timer10)
                stop(app.timer5)
                stop(app.mainTimer)
                delete(app);
            catch ME
                app.WrongFormat1.Text = ME.message;
                app.WrongFormat1.FontColor = 'red';
                app.WrongFormat1.Visible = 'on';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.mainTimer) & isvalid(app.mainTimer)
                stop(app.mainTimer)
            end
            if ~isempty(app.timer10) & isvalid(app.mainTimer)
                stop(app.timer10)
            end
            if ~isempty(app.timer5) & isvalid(app.mainTimer)
                stop(app.timer5)
            end
            delete(app.hstar)
            delete(app.hline)
            delete(app)
        end

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)
            try
                app.WrongFormat1.Visible = 'off'; 
                callingApp = app.CallingApp;
                value = app.BG_value.Value;
                time = str2double(app.BG_time.Value);
                if floor(time)~=time
                    error('popup0:WrongTimeFormat','Time must be an integer.')
                end
                delete(app.hstar)
                delete(app.hline)
                [app.hstar, app.hline] = update_BGplot(callingApp,callingApp.dataStorage,...
                    time,value);
            catch ME
                app.WrongFormat1.Visible = 'on';         
                app.WrongFormat1.Text = ME.message;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.Enable = 'off';
            app.SaveButton.Visible = 'off';
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Enable = 'off';
            app.CancelButton.Visible = 'off';
            app.CancelButton.Text = 'Cancel (??)';
            app.InsertnewdatumLabel = uilabel(app.UIFigure);
            app.InsertnewdatumLabel.Text = 'Insert new datum';
            app.BloodglucosereadingPanel = uipanel(app.UIFigure);
            app.BloodglucosereadingPanel.Title = 'Blood glucose reading';
            app.ValueLabel_2 = uilabel(app.BloodglucosereadingPanel);
            app.ValueLabel_2.Text = 'Value:';
            app.BG_value = uieditfield(app.BloodglucosereadingPanel, 'numeric');
            app.BG_value.Limits = [0 Inf];
            app.BG_value.Tooltip = {'Insert numeric value'};
            app.BG_measUnit_label = uilabel(app.BloodglucosereadingPanel);
            app.BG_measUnit_label.Text = 'mg/dL';
            app.BG_time = uieditfield(app.BloodglucosereadingPanel, 'text');
            app.BG_time.Tooltip = {'Minutes'};
            app.BG_measUnit_label_2 = uilabel(app.BloodglucosereadingPanel);
            app.BG_measUnit_label_2.Text = 'Time:';
            app.Now_BG = uibutton(app.BloodglucosereadingPanel, 'push');
            app.Now_BG.ButtonPushedFcn = createCallbackFcn(app, @Now_BGButtonPushed, true);
            app.Now_BG.Text = 'now';
            app.Confirm_BG = uibutton(app.BloodglucosereadingPanel, 'push');
            app.Confirm_BG.ButtonPushedFcn = createCallbackFcn(app, @Confirm_BGButtonPushed, true);
            app.Confirm_BG.Text = 'Confirm';
            app.WrongFormat1 = uilabel(app.BloodglucosereadingPanel);
            app.WrongFormat1.Visible = 'off';
            app.CheckButton = uibutton(app.BloodglucosereadingPanel, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Text = 'Check';
            app.Label = uilabel(app.UIFigure);
            app.Label.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = window_popup_reading(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end