classdef window_settings2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TypeofclampPanel               matlab.ui.container.Panel
        TypeofclampexperimentLabel     matlab.ui.control.Label
        TypeofclampexperimentDropDown  matlab.ui.control.DropDown
        ChooseSavingFolderButton       matlab.ui.control.Button
        AdvancedsettingsButton         matlab.ui.control.Button
        ProtocolsettingsPanel_2        matlab.ui.container.Panel
        target_mu                      matlab.ui.control.Label
        DesiredglycemicplateauLabel    matlab.ui.control.Label
        target                         matlab.ui.control.NumericEditField
        minLabel_2                     matlab.ui.control.Label
        InsulinbolustimeLabel          matlab.ui.control.Label
        insBolus_time                  matlab.ui.control.NumericEditField
        InsulininfusionstarttimeLabel  matlab.ui.control.Label
        insBasal_time                  matlab.ui.control.NumericEditField
        ULabel                         matlab.ui.control.Label
        InsulinsolutionbolusdoseEditFieldLabel  matlab.ui.control.Label
        insBolus                       matlab.ui.control.NumericEditField
        InsulinsolutioninfusionrateEditFieldLabel  matlab.ui.control.Label
        insBasal                       matlab.ui.control.NumericEditField
        minLabel                       matlab.ui.control.Label
        mlhLabel_2                     matlab.ui.control.Label
        FillDataLabel                  matlab.ui.control.Label
        ConfirmButton                  matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        PatientdataPanel               matlab.ui.container.Panel
        HeightEditField                matlab.ui.control.NumericEditField
        HeightEditFieldLabel           matlab.ui.control.Label
        BodyweightEditField            matlab.ui.control.NumericEditField
        BodyweightEditFieldLabel       matlab.ui.control.Label
        AgeEditField                   matlab.ui.control.NumericEditField
        AgeEditFieldLabel              matlab.ui.control.Label
        GenderDropDown                 matlab.ui.control.DropDown
        GenderDropDownLabel            matlab.ui.control.Label
        GroupEditField                 matlab.ui.control.EditField
        GroupEditFieldLabel            matlab.ui.control.Label
        PatientIDEditField             matlab.ui.control.EditField
        PatientIDEditFieldLabel        matlab.ui.control.Label
        cmLabel                        matlab.ui.control.Label
        KgLabel                        matlab.ui.control.Label
        SettingsLabel                  matlab.ui.control.Label
    end

    
    properties (Access = public)
        measUnit 
        protocol
        savingDir
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,'center')
            app.UIFigure.Visible = 'on';
            app.UIFigure.Name = 'Settings';
            
            % Initialize protocol
            protocol_insBasal = 40;                     % [mU/Kg]
            protocol_insBolus = 0;                      % [U/kg]
            protocol_insConc = 1;                       % [U/ml]
            protocol_dexConc = .2;                      % [g/min or %]
            protocol_clampType = 'hypoglycemic';        % {hypo, eu, hyper, custom}
            protocol_refPlateau = 2.5;                  % mmol/l
            
            app.protocol = Protocol(protocol_clampType, protocol_refPlateau, protocol_insBasal, ...
                protocol_insBolus, protocol_insConc, protocol_dexConc);
            
            % Initialize measurement units
            app.measUnit = MeasUnit();
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            if isempty(app.PatientIDEditField.Value) | isempty(app.GroupEditField.Value)
                app.FillDataLabel.Visible = 'on';
                app.FillDataLabel.Text = 'Please fill all patient data';
            elseif isempty(app.savingDir) | app.savingDir == 0
                app.FillDataLabel.Visible = 'on';
                app.FillDataLabel.Text = 'Please choose a saving folder';
            elseif ~isempty(regexp(app.PatientIDEditField.Value, '[/\*:?"<>|]', 'once'))
                app.FillDataLabel.Visible = 'on';
                app.FillDataLabel.Text = 'Invalid character in patient ID';                
            else
                app.FillDataLabel.Visible = 'off';
                
                % Initialize patient demographic data
                patientData = PatientData();
                
                patientData.ID = app.PatientIDEditField.Value;
                patientData.group = app.GroupEditField.Value;
                patientData.gender = app.GenderDropDown.Value; 
                patientData.age = app.AgeEditField.Value;
                patientData.BW = app.BodyweightEditField.Value;
                patientData.height = app.HeightEditField.Value;
                
                % Initialize data storage 
                dataStorage = DataStorage();
                
                % Initialize extra data storage
                extraDataStorage = ExtraDataStorage();
                
                extraDataStorage.insulin.bolus.value = app.insBolus.Value;
                extraDataStorage.insulin.bolus.time = app.insBolus_time.Value;
                extraDataStorage.insulin.infusionRateChange.value = app.insBasal.Value;
                extraDataStorage.insulin.infusionRateChange.time = app.insBasal_time.Value;
                extraDataStorage.oralGlucose.value = 0;
                extraDataStorage.oralGlucose.time = 0;

                refPlateau = app.target.Value;
                if strcmp(app.measUnit.BG,'mg/dl') 
                    gain = 18.0186;
                else 
                    gain = 1;
                end
                app.protocol.refPlateau = refPlateau/gain;

                window_summary(patientData, dataStorage, app.measUnit, app.protocol, ...
                    extraDataStorage, app.savingDir)
                delete(app)
            end
        end

        % Button pushed function: AdvancedsettingsButton
        function AdvancedsettingsButtonPushed(app, event)
            window_advancedsettings(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            window_start
            delete(app)
        end

        % Value changed function: BodyweightEditField
        function BodyweightEditFieldValueChanged(app, event)
            bw = app.BodyweightEditField.Value;
            h = app.HeightEditField.Value;
            BSA = 0.007184*(bw^0.425)*(h^0.725);
                        
            ins_basal_tmp = app.protocol.getInsInfusionRate(BSA);
            ins_basal_tmp = round(ins_basal_tmp,2);
            
            app.insBasal.Value = ins_basal_tmp;
            app.insBolus.Value = app.protocol.getInsBolus(bw);
        
        end

        % Value changed function: HeightEditField
        function HeightEditFieldValueChanged(app, event)
            bw = app.BodyweightEditField.Value;
            h = app.HeightEditField.Value;
            BSA = 0.007184*(bw^0.425)*(h^0.725);
                        
            ins_basal_tmp = app.protocol.getInsInfusionRate(BSA);
            ins_basal_tmp = round(ins_basal_tmp,2);
            app.insBasal.Value = ins_basal_tmp;
            
            ins_bolus_tmp = app.protocol.getInsBolus(bw);
            ins_bolus_tmp = round(ins_bolus_tmp,2);
            app.insBolus.Value = ins_bolus_tmp;
        end

        % Button pushed function: ChooseSavingFolderButton
        function ChooseSavingFolderButtonPushed(app, event)
            dir =  uigetdir;
            app.savingDir = dir;
            drawnow;
            figure(app.UIFigure)
            app.FillDataLabel.Visible = 'off';
        end

        % Value changed function: TypeofclampexperimentDropDown
        function TypeofclampexperimentDropDownValueChanged(app, event)
            value = app.TypeofclampexperimentDropDown.Value;
            if strcmp(app.measUnit.BG,'mg/dl') 
                gain = 18.0186;
            else 
                gain = 1;
            end
            switch value
                case 'Custom'
                    app.target.Editable = 'on';
                    app.protocol.clampType = 'custom';
                case 'Hypoglycemic'
                    app.target.Editable = 'off';
                    app.target.Value = 2.5*gain;
                    app.protocol.clampType = 'hypoglycemic';
                case 'Euglycemic'
                    app.target.Editable = 'off';
                    app.target.Value = 5.5*gain;
                    app.protocol.clampType = 'euglycemic';
                case 'Hyperglycemic'
                    app.target.Editable = 'off';
                    app.target.Value = 12.4*gain;
                    app.protocol.clampType = 'hyperglycemic';
            end
            refPlateau = app.target.Value;
            if strcmp(app.measUnit.BG,'mg/dl') 
                gain = 18.0186;
            else 
                gain = 1;
            end
            app.protocol.refPlateau = refPlateau/gain;
        end

        % Value changed function: target
        function targetValueChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SettingsLabel = uilabel(app.UIFigure);
            app.SettingsLabel.Text = 'Settings';
            app.PatientdataPanel = uipanel(app.UIFigure);
            app.PatientdataPanel.Title = 'Patient data';
            app.KgLabel = uilabel(app.PatientdataPanel);
            app.KgLabel.Text = 'Kg';
            app.cmLabel = uilabel(app.PatientdataPanel);
            app.cmLabel.Text = 'cm';
            app.PatientIDEditFieldLabel = uilabel(app.PatientdataPanel);
            app.PatientIDEditFieldLabel.Text = 'Patient ID';
            app.PatientIDEditField = uieditfield(app.PatientdataPanel, 'text');
            app.GroupEditFieldLabel = uilabel(app.PatientdataPanel);
            app.GroupEditFieldLabel.Text = 'Group';
            app.GroupEditField = uieditfield(app.PatientdataPanel, 'text');
            app.GenderDropDownLabel = uilabel(app.PatientdataPanel);
            app.GenderDropDownLabel.Text = 'Gender';
            app.GenderDropDown = uidropdown(app.PatientdataPanel);
            app.GenderDropDown.Items = {'Female', 'Male', 'N/A'};
            app.GenderDropDown.Value = 'Female';
            app.AgeEditFieldLabel = uilabel(app.PatientdataPanel);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.PatientdataPanel, 'numeric');
            app.BodyweightEditFieldLabel = uilabel(app.PatientdataPanel);
            app.BodyweightEditFieldLabel.Text = 'Body weight';
            app.BodyweightEditField = uieditfield(app.PatientdataPanel, 'numeric');
            app.BodyweightEditField.ValueChangedFcn = createCallbackFcn(app, @BodyweightEditFieldValueChanged, true);
            app.HeightEditFieldLabel = uilabel(app.PatientdataPanel);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.PatientdataPanel, 'numeric');
            app.HeightEditField.ValueChangedFcn = createCallbackFcn(app, @HeightEditFieldValueChanged, true);
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.FillDataLabel = uilabel(app.UIFigure);
            app.FillDataLabel.Visible = 'off';
            app.ProtocolsettingsPanel_2 = uipanel(app.UIFigure);
            app.ProtocolsettingsPanel_2.Title = 'Protocol settings';
            app.mlhLabel_2 = uilabel(app.ProtocolsettingsPanel_2);
            app.mlhLabel_2.Text = 'ml/h';
            app.minLabel = uilabel(app.ProtocolsettingsPanel_2);
            app.minLabel.Text = 'min';
            app.insBasal = uieditfield(app.ProtocolsettingsPanel_2, 'numeric');
            app.InsulinsolutioninfusionrateEditFieldLabel = uilabel(app.ProtocolsettingsPanel_2);
            app.InsulinsolutioninfusionrateEditFieldLabel.Text = 'Insulin solution infusion rate:';
            app.insBolus = uieditfield(app.ProtocolsettingsPanel_2, 'numeric');
            app.InsulinsolutionbolusdoseEditFieldLabel = uilabel(app.ProtocolsettingsPanel_2);
            app.InsulinsolutionbolusdoseEditFieldLabel.Text = 'Insulin solution bolus dose:';
            app.ULabel = uilabel(app.ProtocolsettingsPanel_2);
            app.ULabel.Text = 'U';
            app.insBasal_time = uieditfield(app.ProtocolsettingsPanel_2, 'numeric');
            app.insBasal_time.Tooltip = {'With respect to experiment time t=0 min.'};
            app.InsulininfusionstarttimeLabel = uilabel(app.ProtocolsettingsPanel_2);
            app.InsulininfusionstarttimeLabel.Text = 'Insulin infusion start time:';
            app.insBolus_time = uieditfield(app.ProtocolsettingsPanel_2, 'numeric');
            app.insBolus_time.Tooltip = {'With respect to experiment time t=0 min.'};
            app.InsulinbolustimeLabel = uilabel(app.ProtocolsettingsPanel_2);
            app.InsulinbolustimeLabel.Text = 'Insulin bolus time:';
            app.minLabel_2 = uilabel(app.ProtocolsettingsPanel_2);
            app.minLabel_2.Text = 'min';
            app.target = uieditfield(app.ProtocolsettingsPanel_2, 'numeric');
            app.target.ValueChangedFcn = createCallbackFcn(app, @targetValueChanged, true);
            app.target.Editable = 'off';
            app.target.Value = 2.5;
            app.DesiredglycemicplateauLabel = uilabel(app.ProtocolsettingsPanel_2);
            app.DesiredglycemicplateauLabel.Text = 'Desired glycemic plateau: ';
            app.target_mu = uilabel(app.ProtocolsettingsPanel_2);
            app.target_mu.Text = 'mmol/l';
            app.AdvancedsettingsButton = uibutton(app.UIFigure, 'push');
            app.AdvancedsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @AdvancedsettingsButtonPushed, true);
            app.AdvancedsettingsButton.Text = 'Advanced settings';
            app.ChooseSavingFolderButton = uibutton(app.UIFigure, 'push');
            app.ChooseSavingFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseSavingFolderButtonPushed, true);
            app.ChooseSavingFolderButton.Text = 'Choose Saving Folder';
            app.TypeofclampPanel = uipanel(app.UIFigure);
            app.TypeofclampPanel.Title = 'Type of clamp';
            app.TypeofclampexperimentDropDown = uidropdown(app.TypeofclampPanel);
            app.TypeofclampexperimentDropDown.Items = {'Hypoglycemic', 'Euglycemic', 'Hyperglycemic', 'Custom'};
            app.TypeofclampexperimentDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeofclampexperimentDropDownValueChanged, true);
            app.TypeofclampexperimentDropDown.Value = 'Hypoglycemic';
            app.TypeofclampexperimentLabel = uilabel(app.TypeofclampPanel);
            app.TypeofclampexperimentLabel.Text = 'Type of clamp experiment:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = window_settings2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end