classdef window_start < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ContinueprevioustrialPanel  matlab.ui.container.Panel
        LoadbackupfileButton        matlab.ui.control.Button
        NewpatientPanel             matlab.ui.container.Panel
        StartnewexperimentButton    matlab.ui.control.Button
        WelcomeLabel                matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,'center')
            app.UIFigure.Visible = 'on';
            app.UIFigure.Name = 'Start';
        end

        % Button pushed function: StartnewexperimentButton
        function StartnewexperimentButtonPushed(app, event)
            window_settings2
            delete(app)
        end

        % Button pushed function: LoadbackupfileButton
        function LoadbackupfileButtonPushed(app, event)
            [file,path] = uigetfile('*.mat',...
                'Select a backup file',fullfile('Data','Backups/'));
            if file~=0
                filepath = fullfile(path,file);
                load(filepath,'patientData_backup','dataStorage_backup',...
                'measUnit_backup','protocol_backup',...
                'extraDataStorage_backup','saving_dir',...
                'notes_backup','calibration_time','expStatus')
                window_summary(patientData_backup,dataStorage_backup,...
                    measUnit_backup,protocol_backup,...
                    extraDataStorage_backup,saving_dir,notes_backup,...
                    calibration_time,expStatus)
                pause(2)
                delete(app)
            else 
                drawnow;
                figure(app.UIFigure)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.WelcomeLabel = uilabel(app.UIFigure);
            app.WelcomeLabel.Text = 'Welcome';
            app.NewpatientPanel = uipanel(app.UIFigure);
            app.NewpatientPanel.Title = 'New patient';
            app.StartnewexperimentButton = uibutton(app.NewpatientPanel, 'push');
            app.StartnewexperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StartnewexperimentButtonPushed, true);
            app.StartnewexperimentButton.Text = 'Start new experiment';
            app.ContinueprevioustrialPanel = uipanel(app.UIFigure);
            app.ContinueprevioustrialPanel.Title = 'Continue previous trial';
            app.LoadbackupfileButton = uibutton(app.ContinueprevioustrialPanel, 'push');
            app.LoadbackupfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadbackupfileButtonPushed, true);
            app.LoadbackupfileButton.Text = 'Load back-up file';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = window_start(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end