classdef window_summary < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        WarningLabel              matlab.ui.control.Label
        CalibratetimeButton       matlab.ui.control.Button
        StopExperimentButton      matlab.ui.control.Button
        InduceEuglycemiaButton    matlab.ui.control.Button
        WrongFormat1              matlab.ui.control.Label
        NotesandremarksPanel      matlab.ui.container.Panel
        Confirm_note              matlab.ui.control.Button
        Now_note                  matlab.ui.control.Button
        InputTimeNote             matlab.ui.control.EditField
        NoteTime                  matlab.ui.control.Label
        NoteTextArea              matlab.ui.control.TextArea
        NoteTextAreaLabel         matlab.ui.control.Label
        ControltoreferenceButton  matlab.ui.control.Button
        LastdatapointsPanel       matlab.ui.container.Panel
        LastGIR                   matlab.ui.control.Label
        LastBG                    matlab.ui.control.Label
        ExtrainputsandinfusionratechangesPanel  matlab.ui.container.Panel
        InputDropDown             matlab.ui.control.DropDown
        InputDropDownLabel        matlab.ui.control.Label
        InputValueEditField       matlab.ui.control.NumericEditField
        ValueEditFieldLabel       matlab.ui.control.Label
        ConfirmChanges            matlab.ui.control.Button
        NowChanges                matlab.ui.control.Button
        InputTimeEditField        matlab.ui.control.EditField
        InputUnitMeasLabel        matlab.ui.control.Label
        component                 matlab.ui.control.Label
        PatientdataPanel          matlab.ui.container.Panel
        PatAge                    matlab.ui.control.Label
        PatGender                 matlab.ui.control.Label
        PatHeight                 matlab.ui.control.Label
        PatBW                     matlab.ui.control.Label
        PatGroup                  matlab.ui.control.Label
        PatID                     matlab.ui.control.Label
        SaveresultsButton         matlab.ui.control.Button
        CorrectdataButton         matlab.ui.control.Button
        StartreadingsButton       matlab.ui.control.Button
        Axes_GIR                  matlab.ui.control.UIAxes
        Axes_glucose_readings     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        protocol
        dataStorage
        extraDataStorage
        patientData
        measUnit
        notes
        
        ExperimentStatus
        TimeCalibration
        timer
        period
        
        control_parameters
        reference
        StartControlTime
        
        savingDir
    end
    
    methods (Access = public)
        
        function [hstar, hline] = update_BGplot(app,ds,varargin)
            %mu_BG = app.measUnit.BG;
            t = ds.BG_time;
            t_tmp = datetime('now'); 
            t_now = timeFromStart(t_tmp,app.TimeCalibration);
            y = ds.BG_value;
            axInnerPos = app.Axes_glucose_readings.InnerPosition;   
            
            % BG axis
            %ylabel(app.Axes_glucose_readings,sprintf('Blood glucose [%s]',mu_BG))
            h = plot(app.Axes_glucose_readings,t,y,'-o');
            hold(app.Axes_glucose_readings,'on')
            
            if ~isempty(varargin)
                time2 = varargin{1};
                value2 = varargin{2};
                hstar = plot(app.Axes_glucose_readings,time2,value2,'pk');
                vect_value = ds.BG_value;
                vect_time = ds.BG_time;
                if ~isempty(vect_value)
                    value1 = vect_value(end);
                    time1 = vect_time(end);
                    hline = plot(app.Axes_glucose_readings,...
                        [time1 time2],[value1 value2],'-.k');
                else 
                    hline = [];
                end
                t_tmp = [vect_time; time2];
                y_tmp = [vect_value; value2];
                ylim(app.Axes_glucose_readings,[min([1.6,.8*min(y_tmp)]) max([5,1.2*max(y_tmp)])])
                xlim(app.Axes_glucose_readings,[app.TimeCalibration.exptime,max(t_tmp)+5])
            end
            
            if ~isempty(app.extraDataStorage.extra_BG.time)
                plot(app.Axes_glucose_readings,...
                        app.extraDataStorage.extra_BG.time,app.extraDataStorage.extra_BG.value,'xb');
            end
            
            switch app.measUnit.BG
                case 'mmol/l'
                    gain = 1;
                case 'mg/dl'
                    gain = 18.0186;
            end
            
            switch app.ExperimentStatus
                case 'control2ref'
                    stc = app.StartControlTime;
                    switch app.protocol.clampType
                        case 'Hypoglycemic'
                            t_target = [stc, stc+25, stc+55, stc+70, max([stc+80,t_now])];
                            y_target = [3.885 3.885 2.775 2.5 2.5]*gain;
                        otherwise 
                            t_target = [stc, max([stc+120,t_now])];
                            y_target = [app.protocol.refPlateau, app.protocol.refPlateau]*gain;
                    end
                    
                    h1=plot(app.Axes_glucose_readings,t_target,y_target,'-.k');   
                    legend(app.Axes_glucose_readings,h1,'BG target','Location','southwest')
                    
                case 'control2eu'
                    stc = app.StartControlTime;
                    t_target = [stc max([stc+30,t_now])];
                    y_target = [4.5 4.5]*gain;
                    
                    h1=plot(app.Axes_glucose_readings,t_target,y_target,'-.k'); 
                    legend(app.Axes_glucose_readings,h1,'BG target','Location','southwest')
            end
            
            hold(app.Axes_glucose_readings,'off')
            if ~isempty(t) && isempty(varargin)
                ylim(app.Axes_glucose_readings,[min([1.6,.8*min(y)]) max([5,1.2*max(y)])])
                xlim(app.Axes_glucose_readings,[app.TimeCalibration.exptime,max(t)+5])
            end
            
            innerPosDiff = axInnerPos - app.Axes_glucose_readings.InnerPosition;   
            app.Axes_glucose_readings.Position = ...
                app.Axes_glucose_readings.Position + innerPosDiff;
        end
    
        function update_GIRplot(app,ds)
            mu_GIR = app.measUnit.GIR;
            t = ds.GIR_time;
            t_start = 0;                
            y1 = ds.suggGIR_value;
            y2 = ds.realGIR_value;
            axInnerPos = app.Axes_GIR.InnerPosition; 
            h1 = stairs(app.Axes_GIR,t,y1,'-ob');
            hold(app.Axes_GIR,'on')
            h2 = stairs(app.Axes_GIR,t,y2,'-or');
            t_target = t_start;
            y_target = 0; 
            plot(app.Axes_GIR,t_target,y_target,'w')
            legend(app.Axes_GIR,[h1,h2],'Suggested GIR','Actual GIR','Location','southwest')
            ylabel(app.Axes_GIR,sprintf('Dextrose infusion [%s]',mu_GIR))
            ylim(app.Axes_GIR,[0 max([80,(max(y1)+10),max(y2)+10])])
            if ~isempty(t)
                xlim(app.Axes_GIR,[app.TimeCalibration.exptime,max(t)+5])
            end
            hold(app.Axes_GIR,'off')
            %linkaxes([app.Axes_glucose_readings,app.Axes_GIR],'x');            
            innerPosDiff = axInnerPos - app.Axes_GIR.InnerPosition;   
            app.Axes_GIR.Position = ...
                app.Axes_GIR.Position + innerPosDiff;            
        end    
    
        function update_pat_infos(app,pd)
            patID = pd.ID;
            patGroup = pd.group;
            patBW = pd.BW;
            patHeight = pd.height;
            patAge = pd.age;
            patGender = pd.gender;
            
            app.PatID.Text = sprintf('ID: %s',patID);
            app.PatGroup.Text = sprintf('Group: %s',patGroup);
            app.PatBW.Text = sprintf('Body weight: %.1f Kg',patBW);
            app.PatHeight.Text = sprintf('Height: %i cm',patHeight);
            app.PatAge.Text = sprintf('%i years',patAge);
            app.PatGender.Text = sprintf('Gender %s',patGender);
        end
    
        function update_last_points(app,y1,t1,y2,t2)
            mu_BG = app.measUnit.BG;
            mu_GIR = app.measUnit.GIR;
            if ~isempty(t1)
                app.LastBG.Text = ...
                    sprintf('Last BG reading: %.2f %s at minute %s',y1,mu_BG,num2str(t1));
            else 
                app.LastBG.Text = ...
                    sprintf('Last BG reading: %s %s at minute %s','..',mu_BG,'..');  
            end
            if ~isempty(t2)
                app.LastGIR.Text = ...
                    sprintf('Last dextrose infusion: %.2f %s at minute %s',y2,mu_GIR,num2str(t2));
            else    
                app.LastGIR.Text = ...
                    sprintf('Last dextrose infusion: %.2f %s at minute %s',y2,mu_GIR,'..');
            end
                
        end
            
        function save_backup(app)
            dataStorage_backup = app.dataStorage;
            patientData_backup = app.patientData;
            extraDataStorage_backup = app.extraDataStorage;
            protocol_backup = app.protocol;
            measUnit_backup = app.measUnit;
            notes_backup = app.notes;
            calibration_time = app.TimeCalibration;
            expStatus = app.ExperimentStatus;
            
            try 
                ID = app.patientData.ID;
                date2day = char(datetime('today','Format','MMdd'));
                filename = [ID,'_',date2day];
                if ~exist(fullfile(app.savingDir,'Data','Backups'), 'dir')
                    mkdir(fullfile(app.savingDir,'Data','Backups'))
                end
                filepath = fullfile(app.savingDir,'Data','Backups',filename);
                saving_dir = app.savingDir;
                save(filepath,'patientData_backup','dataStorage_backup',...
                    'measUnit_backup','protocol_backup',...
                    'extraDataStorage_backup','notes_backup',...
                    'calibration_time','expStatus','saving_dir')
                app.WarningLabel.Visible = 'off';
            catch ME
                app.WarningLabel.Visible = 'on';
                if ~exist(fullfile(app.savingDir,'Data','Logs'), 'dir')
                    mkdir(fullfile(app.savingDir,'Data','Logs'))
                end
                fileID = fopen(fullfile(app.savingDir,'Data','Logs','errors.txt'),'w');
                fprintf(fileID,'%s',ME.message);
            end
        end
    
        function save_results(app)
            ID = app.patientData.ID;
            date2day = char(datetime('today','Format','MMdd'));
            if ~exist(fullfile(app.savingDir,'Data','Results'), 'dir')
                mkdir(fullfile(app.savingDir,'Data','Results'))
            end            
            filename = [ID,'_',date2day];
            [file,path] =  uiputfile('*.xlsx',...
                'Save results',fullfile(app.savingDir,'Data','Results',filename));
            filedir = fullfile(path,file);
            
            %% ----  Inputs  ---- %%
            % BG table
            [bg,bg_time] = app.dataStorage.getBG();
            T_BG = table(bg_time, bg, ...
                'VariableNames', {'Time','Blood_Glucose'});
            writetable(T_BG,filedir,'Sheet',1);
            
            % GIR table
            [gir_sugg,gir_real,gir_time] = app.dataStorage.getGIR();
            T_GIR = table(gir_time, gir_sugg, gir_real, ...
                'VariableNames', {'Time','Suggested_GIR','Actual_GIR'});
            writetable(T_GIR,filedir,'Range','D1','Sheet',1);
            
            %% ----  Extra inputs table  ---- %%
            
            %% oralGlucose table
            oralGluc_time = app.extraDataStorage.oralGlucose.time;
            oralGluc_value = app.extraDataStorage.oralGlucose.value;
            T_OG = table(oralGluc_time, oralGluc_value, ...
                'VariableNames', {'Time','Oral_Glucose_Intake'});
            %T_OG.ColumnName = ...
            %        {'Time',sprintf('Oral glucose [%s]','g')};
            writetable(T_OG,filedir,'Sheet',2);
            
            %% Insulin
            % bolus
            ins_bolus_value = ...
                app.extraDataStorage.insulin.bolus.value;
            ins_bolus_time = ...
                app.extraDataStorage.insulin.bolus.time;
            ins_bolus_type = cell(length(ins_bolus_time),1);
            ins_bolus_type(:) = {'bolus [ml]'};
            T_bolus = table(ins_bolus_time,ins_bolus_value,...
                ins_bolus_type,'VariableNames',...
                {'Time','Insulin','Type'});
            % basal
            ins_basal_value = ...
                app.extraDataStorage.insulin.infusionRateChange.value;
            ins_basal_time = ...
                app.extraDataStorage.insulin.infusionRateChange.time;
            ins_basal_type = cell(length(ins_basal_time),1);
            ins_basal_type(:) = {'basal [ml/h]'};
            T_basal = table(ins_basal_time,ins_basal_value,...
                ins_basal_type,'VariableNames',...
                {'Time','Insulin','Type'});  
            % merge    
            T_INS = [T_bolus;T_basal];
            T_INS = sortrows(T_INS,{'Time'});
            %T_INS.ColumnName = ...
            %    {'Time','Insulin','Type'};
            writetable(T_INS,filedir,'Range','D1','Sheet',2);
            
            %% 2H glucose
            % bolus
            gluc2h_bolus_value = ...
                app.extraDataStorage.glucose2H.bolus.value;
            gluc2h_bolus_time = ...
                app.extraDataStorage.glucose2H.bolus.time;
            gluc2h_bolus_type = cell(length(gluc2h_bolus_time),1);
            gluc2h_bolus_type(:) = {'bolus [ml]'};
            T_bolus = table(gluc2h_bolus_time,gluc2h_bolus_value,...
                gluc2h_bolus_type,'VariableNames',...
                {'Time','Glucose_2H','Type'});
            % basal
            gluc2h_basal_value = ...
                app.extraDataStorage.glucose2H.infusionRateChange.value;
            gluc2h_basal_time = ...
                app.extraDataStorage.glucose2H.infusionRateChange.time;
            gluc2h_basal_type = cell(length(gluc2h_basal_time),1);
            gluc2h_basal_type(:) = {'basal [ml/h]'};
            T_basal = table(gluc2h_basal_time,gluc2h_basal_value,...
                gluc2h_basal_type,'VariableNames',...
                {'Time','Glucose_2H','Type'});
            % merge
            T_2HGLUC = [T_bolus;T_basal];
            T_2HGLUC = sortrows(T_2HGLUC,{'Time'});
            %T_2HGLUC.ColumnName = ...
            %    {'Time','2H glucose','Type'};
            writetable(T_2HGLUC,filedir,'Range','H1','Sheet',2);
            
            %% oralGlucose table
            extra_BG_time = app.extraDataStorage.extra_BG.time;
            extra_BG_value = app.extraDataStorage.extra_BG.value;
            T_EBG = table(extra_BG_time, extra_BG_value, ...
                'VariableNames', {'Time','Extra_BG_readings'});
            %T_OG.ColumnName = ...
            %        {'Time',sprintf('Oral glucose [%s]','g')};
            writetable(T_EBG,filedir,'Range','L1','Sheet',2);
                           
            %% ---- demagraphics table  ---- %%
            [id,group,bw,h] = app.patientData.getPatientInfos();              
            T_pat = cell2table({id, group, bw, h}, ...
                'VariableNames',{'ID', 'Group', 'BW', 'Height'});
            writetable(T_pat,filedir,'Sheet',3)    
                                            
            %% ---- notes and remarks ---- %%
            if ~isempty(app.notes.Text)
                T_notes_text = cell2table(app.notes.Text);
                T_notes_text.Properties.VariableNames = {'Text'};
                T_notes_time = table(app.notes.Time,...
                    'VariableNames',{'Time'});
                T_notes = [T_notes_time, T_notes_text];
                
                %T_notes = table(app.notes.Time, app.notes.Text{:}, ...
                %    'VariableNames',{'Time','Notes'});
                writetable(T_notes,filedir,'Sheet',4)
            end
                            
        end
            
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,'center')
            app.UIFigure.Visible = 'on'; 
            app.UIFigure.Name = 'Experiment';
            
            % Patient data
            pd = varargin{1};
            update_pat_infos(app,pd);
            app.patientData = pd;
            
            % BG and GIR
            ds = varargin{2};
            app.dataStorage = ds;
            
            % Measurement units
            mu = varargin{3};
            app.measUnit = mu;
            
            % Protocol
            app.protocol = varargin{4};
            app.extraDataStorage = varargin{5};
            
            app.savingDir = varargin{6};
            
            %% init with back-up or new experiment?
            % backing up
            if nargin>7                                             
                app.notes = varargin{7};
                app.TimeCalibration = varargin{8};
                [y1,t1] = ds.getBG();
                t1 = t1(end);
                y1 = y1(end);
                [y2, ~, t2] = ds.getGIR();
                if isempty(y2)
                    t2 = [];
                    y2 = [];
                else 
                    t2 = t2(end);
                    y2 = y2(end);
                end
                update_last_points(app, y1, t1, y2, t2);
                expStatus = varargin{9}; 
                app.ExperimentStatus = expStatus;
                app.CalibratetimeButton.Enable = 'off';
                app.NowChanges.Enable = 'on';
                app.Now_note.Enable = 'on';
                app.ConfirmChanges.Enable = 'on';
                app.StartreadingsButton.Enable = 'on';
                switch app.ExperimentStatus
                    case 'reading'
                        %app.reference = app.protocol.referenceHG; % mmol/L
                    case 'control2eu'
                        %app.reference = app.protocol.referenceEG; % mmol/L
                        app.ControltoreferenceButton.Enable = 'on';
                        app.InduceEuglycemiaButton.Enable = 'on';
                    case 'control2ref' 
                        %app.reference = app.protocol.referenceHG; % mmol/L
                        app.ControltoreferenceButton.Enable = 'on';
                        app.InduceEuglycemiaButton.Enable = 'on';
                    case 'stop'
                        %app.reference = app.protocol.referenceHG; % mmol/L
                        app.ControltoreferenceButton.Enable = 'on';
                        app.InduceEuglycemiaButton.Enable = 'on';
                end 
                update_BGplot(app,ds)
                update_GIRplot(app,ds)    
                app.StartreadingsButton.Tooltip = '';
                app.ControltoreferenceButton.Tooltip = '';
                app.InduceEuglycemiaButton.Tooltip = '';
                app.NowChanges.Enable = 'on';
                app.NowChanges.Tooltip = '';
                app.Now_note.Enable = 'on';
                app.Now_note.Tooltip = '';
                app.CorrectdataButton.Enable = 'on';
                app.ConfirmChanges.Enable = 'on';     
                
            % new experiment    
            else                                                    
                %app.reference = app.protocol.referenceHG; % mmol/L
                app.notes.Time = [];
                app.notes.Text = {};
                update_last_points(app, [], [], [], []);
            end                
            
            % init other properties
            app.period = 300; % seconds
            
            app.Axes_glucose_readings.YLabel.String = sprintf('Glucose reading [%s]',app.measUnit.BG);
            app.Axes_GIR.YLabel.String = 'Dextrose infusion [ml/h]';
            app.Axes_GIR.XLabel.String = 'Experimental time [min]';
                 
            switch app.protocol.clampType
                case 'Hypoglycemic'
                    load(which(fullfile('ControllerParametersHypo.mat')),'controller_parameters');
                case 'Euglycemic'
                    load(which(fullfile('ControllerParametersEu.mat')),'controller_parameters');
                otherwise
                    load(which(fullfile('ControllerParametersHyper.mat')),'controller_parameters');
            end
            app.control_parameters = controller_parameters;
        end

        % Button pushed function: StartreadingsButton
        function StartreadingsButtonPushed(app, event)
            app.timer = popupTimer_reading(app, app.period);
            start(app.timer);
            app.ExperimentStatus = 'reading'; 
              
            % enable buttons
            app.CalibratetimeButton.Enable = 'off';
            app.ConfirmChanges.Enable = 'on';    
            app.ControltoreferenceButton.Enable = 'on';
            app.InduceEuglycemiaButton.Enable = 'on';
            app.StopExperimentButton.Enable = 'on';
            app.StartreadingsButton.Enable = 'off';
            app.SaveresultsButton.Enable = 'off';

        end

        % Button pushed function: SaveresultsButton
        function SaveresultsButtonPushed(app, event)
            save_results(app)
        end

        % Button pushed function: CorrectdataButton
        function CorrectdataButtonPushed(app, event)
            window_correction(app, app.dataStorage, app.extraDataStorage, app.savingDir)
        end

        % Button pushed function: NowChanges
        function NowChangesButtonPushed(app, event)
            t_tmp = datetime('now', 'Format', 'HH:mm:ss');
            app.InputTimeEditField.Value = num2str(timeFromStart(t_tmp, app.TimeCalibration));
        end

        % Button pushed function: ConfirmChanges
        function ConfirmChangesButtonPushed(app, event)
            try
                app.WrongFormat1.Visible = 'off';
                time_now = str2double(app.InputTimeEditField.Value);
                if floor(time_now)~=time_now
                    error('summary:WrongTimeFormat', 'Time must be an integer.')
                end                
                value_now = app.InputValueEditField.Value;
                switch app.InputDropDown.Value
                    case 'Extra BG measurement'
                        value_past = app.extraDataStorage.extra_BG.value;
                        app.extraDataStorage.extra_BG.value = ...
                            [value_past; value_now];
                        time_past =  app.extraDataStorage.extra_BG.time;
                        app.extraDataStorage.extra_BG.time = ...
                            [time_past; time_now];
                        update_BGplot(app, app.dataStorage)
                    case 'Additional oral glucose intake'
                        value_past = app.extraDataStorage.oralGlucose.value;
                        app.extraDataStorage.oralGlucose.value = ...
                            [value_past; value_now];
                        time_past =  app.extraDataStorage.oralGlucose.time;
                        app.extraDataStorage.oralGlucose.time = ...
                            [time_past; time_now];                      
                    case 'Additional insulin bolus'
                        value_past = app.extraDataStorage.insulin.bolus.value;
                        app.extraDataStorage.insulin.bolus.value = ...
                            [value_past; value_now];
                        time_past =  app.extraDataStorage.insulin.bolus.time;
                        app.extraDataStorage.insulin.bolus.time = ...
                            [time_past; time_now];                                            
                    case 'Insulin infusion rate change'
                        value_past = ...
                            app.extraDataStorage.insulin.infusionRateChange.value;
                        app.extraDataStorage.insulin.infusionRateChange.value = ...
                            [value_past; value_now];
                        time_past =  ...
                            app.extraDataStorage.insulin.infusionRateChange.time;
                        app.extraDataStorage.insulin.infusionRateChange.time = ...
                            [time_past; time_now];
                end
                app.InputValueEditField.Value = 0;
                app.InputTimeEditField.Value = '';   
            catch ME
                app.WrongFormat1.Visible = 'on';
                app.WrongFormat1.Text = ME.message;
            end
        end

        % Button pushed function: ControltoreferenceButton
        function ControltoreferenceButtonPushed(app, event)
            app.ExperimentStatus = 'control2ref';
            out = timerfind;
            for ii=1:length(out)
                stop(out(ii))
                delete(out(ii));
            end
            startControl_tmp = datetime('now','Format','HH:mm:ss');         
            app.StartControlTime = timeFromStart(startControl_tmp, app.TimeCalibration);
            app.timer = popupTimer_control(app, app.period);
            start(app.timer);
            
            app.ControltoreferenceButton.Enable = 'off';
            app.InduceEuglycemiaButton.Enable = 'on';
            app.StopExperimentButton.Enable = 'on';
            app.SaveresultsButton.Enable = 'off';
            
        end

        % Value changed function: InputDropDown
        function InputDropDownValueChanged(app, event)
            switch app.InputDropDown.Value
                case 'Extra BG measurement'
                    app.InputUnitMeasLabel.Text = app.measUnit.BG;
                case 'Additional oral glucose intake'
                    app.InputUnitMeasLabel.Text = 'g';            
                case 'Additional insulin bolus'
                    app.InputUnitMeasLabel.Text = 'ml';                         
                case 'Insulin infusion rate change'
                    app.InputUnitMeasLabel.Text = 'ml/h';
            end              
        end

        % Button pushed function: Confirm_note
        function Confirm_noteButtonPushed(app, event)
            try
                app.WrongFormat1.Visible = 'off';                
                t = str2double(app.InputTimeNote.Value);    
                if floor(t)~=t
                    error('summary:WrongTimeFormat','Time must be an integer.')
                end
                app.notes.Time = [app.notes.Time; t];
                app.notes.Text = [app.notes.Text; app.NoteTextArea.Value];
                app.NoteTextArea.Value = '';
                app.InputTimeNote.Value = '';
            catch ME
                 app.WrongFormat1.Visible = 'on';
                 app.WrongFormat1.Text = ME.message;
            end
        end

        % Button pushed function: Now_note
        function Now_noteButtonPushed(app, event)
            t_tmp = datetime('now','Format','HH:mm:ss');
            app.InputTimeNote.Value = num2str(timeFromStart(t_tmp, app.TimeCalibration));
        end

        % Callback function
        function UIFigureKeyPress(app, event)
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            out = timerfind;
            for ii=1:length(out)
                stop(out(ii))
                delete(out(ii));
            end
            if ~isempty(app.dataStorage.BG_time)
                window_save(app)
            else
                delete(app)
            end
        end

        % Button pushed function: StopExperimentButton
        function StopExperimentButtonPushed(app, event)
            app.ExperimentStatus = 'stop';
            out = timerfind;
            for ii=1:length(out)
                stop(out(ii))
                delete(out(ii));
            end
            app.StartreadingsButton.Enable = 'on';
            app.SaveresultsButton.Enable = 'on';
            app.ControltoreferenceButton.Enable = 'on';
            app.InduceEuglycemiaButton.Enable = 'on';
            app.StopExperimentButton.Enable = 'off';            
        end

        % Button pushed function: InduceEuglycemiaButton
        function InduceEuglycemiaButtonButtonPushed(app, event)
            app.ExperimentStatus = 'control2eu';
            
            startControl_tmp = datetime('now','Format','HH:mm:ss');         
            app.StartControlTime = timeFromStart(startControl_tmp,app.TimeCalibration);
            
            app.ControltoreferenceButton.Enable = 'on';
            app.InduceEuglycemiaButton.Enable = 'off';
            app.StopExperimentButton.Enable = 'on';
            app.SaveresultsButton.Enable = 'off';
        end

        % Button pushed function: CalibratetimeButton
        function CalibratetimeButtonPushed(app, event)
            window_timecalibration(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Axes_glucose_readings = uiaxes(app.UIFigure);
            app.Axes_GIR = uiaxes(app.UIFigure);
            app.StartreadingsButton = uibutton(app.UIFigure, 'push');
            app.StartreadingsButton.ButtonPushedFcn = createCallbackFcn(app, @StartreadingsButtonPushed, true);
            app.StartreadingsButton.Enable = 'off';
            app.StartreadingsButton.Tooltip = {'You need to calibrate experiment time before starting readings.'};
            app.StartreadingsButton.Text = 'Start readings';
            app.CorrectdataButton = uibutton(app.UIFigure, 'push');
            app.CorrectdataButton.ButtonPushedFcn = createCallbackFcn(app, @CorrectdataButtonPushed, true);
            app.CorrectdataButton.Enable = 'off';
            app.CorrectdataButton.Text = 'Correct data';
            app.SaveresultsButton = uibutton(app.UIFigure, 'push');
            app.SaveresultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveresultsButtonPushed, true);
            app.SaveresultsButton.Enable = 'off';
            app.SaveresultsButton.Text = 'Save results';
            app.PatientdataPanel = uipanel(app.UIFigure);
            app.PatientdataPanel.Title = 'Patient data';
            app.PatID = uilabel(app.PatientdataPanel);
            app.PatGroup = uilabel(app.PatientdataPanel);
            app.PatBW = uilabel(app.PatientdataPanel);
            app.PatHeight = uilabel(app.PatientdataPanel);
            app.PatGender = uilabel(app.PatientdataPanel);
            app.PatAge = uilabel(app.PatientdataPanel);
            app.ExtrainputsandinfusionratechangesPanel = uipanel(app.UIFigure);
            app.ExtrainputsandinfusionratechangesPanel.Title = 'Extra inputs and infusion rate changes';
            app.component = uilabel(app.ExtrainputsandinfusionratechangesPanel);
            app.component.Text = 'Time:';
            app.InputUnitMeasLabel = uilabel(app.ExtrainputsandinfusionratechangesPanel);
            app.InputUnitMeasLabel.Text = 'g';
            app.InputTimeEditField = uieditfield(app.ExtrainputsandinfusionratechangesPanel, 'text');
            app.InputTimeEditField.Tooltip = {'Minute'};
            app.NowChanges = uibutton(app.ExtrainputsandinfusionratechangesPanel, 'push');
            app.NowChanges.ButtonPushedFcn = createCallbackFcn(app, @NowChangesButtonPushed, true);
            app.NowChanges.Enable = 'off';
            app.NowChanges.Tooltip = {'You need to calibrate experiment time first.'};
            app.NowChanges.Text = 'now';
            app.ConfirmChanges = uibutton(app.ExtrainputsandinfusionratechangesPanel, 'push');
            app.ConfirmChanges.ButtonPushedFcn = createCallbackFcn(app, @ConfirmChangesButtonPushed, true);
            app.ConfirmChanges.Enable = 'off';
            app.ConfirmChanges.Text = 'Confirm';
            app.ValueEditFieldLabel = uilabel(app.ExtrainputsandinfusionratechangesPanel);
            app.ValueEditFieldLabel.Text = 'Value:';
            app.InputValueEditField = uieditfield(app.ExtrainputsandinfusionratechangesPanel, 'numeric');
            app.InputValueEditField.Tooltip = {'Insert numeric value'};
            app.InputDropDownLabel = uilabel(app.ExtrainputsandinfusionratechangesPanel);
            app.InputDropDownLabel.Text = 'Input:';
            app.InputDropDown = uidropdown(app.ExtrainputsandinfusionratechangesPanel);
            app.InputDropDown.Items = {'Additional oral glucose intake', 'Additional insulin bolus', 'Insulin infusion rate change', 'Extra BG measurement'};
            app.InputDropDown.ValueChangedFcn = createCallbackFcn(app, @InputDropDownValueChanged, true);
            app.InputDropDown.Value = 'Additional oral glucose intake';
            app.LastdatapointsPanel = uipanel(app.UIFigure);
            app.LastdatapointsPanel.Title = 'Last data points';
            app.LastBG = uilabel(app.LastdatapointsPanel);
            app.LastGIR = uilabel(app.LastdatapointsPanel);
            app.ControltoreferenceButton = uibutton(app.UIFigure, 'push');
            app.ControltoreferenceButton.ButtonPushedFcn = createCallbackFcn(app, @ControltoreferenceButtonPushed, true);
            app.ControltoreferenceButton.Enable = 'off';
            app.ControltoreferenceButton.Tooltip = {'You need to calibrate experiment time before starting control.'};
            app.ControltoreferenceButton.Text = 'Control to reference';
            app.NotesandremarksPanel = uipanel(app.UIFigure);
            app.NotesandremarksPanel.Title = 'Notes and remarks';
            app.NoteTextAreaLabel = uilabel(app.NotesandremarksPanel);
            app.NoteTextAreaLabel.Text = 'Note:';
            app.NoteTextArea = uitextarea(app.NotesandremarksPanel);
            app.NoteTime = uilabel(app.NotesandremarksPanel);
            app.NoteTime.Text = 'Time:';
            app.InputTimeNote = uieditfield(app.NotesandremarksPanel, 'text');
            app.InputTimeNote.Tooltip = {'Minute'};
            app.Now_note = uibutton(app.NotesandremarksPanel, 'push');
            app.Now_note.ButtonPushedFcn = createCallbackFcn(app, @Now_noteButtonPushed, true);
            app.Now_note.Enable = 'off';
            app.Now_note.Tooltip = {'You need to calibrate experiment time first.'};
            app.Now_note.Text = 'now';
            app.Confirm_note = uibutton(app.NotesandremarksPanel, 'push');
            app.Confirm_note.ButtonPushedFcn = createCallbackFcn(app, @Confirm_noteButtonPushed, true);
            app.Confirm_note.Text = 'Confirm';
            app.WrongFormat1 = uilabel(app.UIFigure);
            app.WrongFormat1.Visible = 'off';
            app.InduceEuglycemiaButton = uibutton(app.UIFigure, 'push');
            app.InduceEuglycemiaButton.ButtonPushedFcn = createCallbackFcn(app, @InduceEuglycemiaButtonButtonPushed, true);
            app.InduceEuglycemiaButton.Enable = 'off';
            app.InduceEuglycemiaButton.Tooltip = {'You need to calibrate experiment time before starting control.'};
            app.InduceEuglycemiaButton.Text = 'Return to euglycemia';
            app.StopExperimentButton = uibutton(app.UIFigure, 'push');
            app.StopExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StopExperimentButtonPushed, true);
            app.StopExperimentButton.Enable = 'off';
            app.StopExperimentButton.Text = 'Stop Experiment';
            app.CalibratetimeButton = uibutton(app.UIFigure, 'push');
            app.CalibratetimeButton.ButtonPushedFcn = createCallbackFcn(app, @CalibratetimeButtonPushed, true);
            app.CalibratetimeButton.Text = 'Calibrate time';
            app.WarningLabel = uilabel(app.UIFigure);
            app.WarningLabel.Visible = 'off';
            app.WarningLabel.Text = 'Warning: the app is encountering a problem in saving a back-up file.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = window_summary(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end