classdef window_timecalibration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TimecalibrationLabel  matlab.ui.control.Label
        CurrentexperimenttimeminuteEditFieldLabel  matlab.ui.control.Label
        CurrentexperimenttimeminuteEditField  matlab.ui.control.NumericEditField
        WarningonceyoustartreadingsyouwillnotbeLabel  matlab.ui.control.Label
        CancelButton          matlab.ui.control.Button
        ConfirmButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            datetime_now = datetime("now"); 
            exptime_now = app.CurrentexperimenttimeminuteEditField.Value;
            app.CallingApp.TimeCalibration.datetime = datetime_now;
            app.CallingApp.TimeCalibration.exptime = exptime_now;
            app.CallingApp.StartreadingsButton.Enable = 'on';
            app.CallingApp.StartreadingsButton.Tooltip = '';
            app.CallingApp.ControltoreferenceButton.Tooltip = '';
            app.CallingApp.InduceEuglycemiaButton.Tooltip = '';
            app.CallingApp.NowChanges.Enable = 'on';
            app.CallingApp.NowChanges.Tooltip = '';
            app.CallingApp.Now_note.Enable = 'on';
            app.CallingApp.Now_note.Tooltip = '';
            app.CallingApp.CorrectdataButton.Enable = 'on';
            app.CallingApp.ConfirmChanges.Enable = 'on';
            
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TimecalibrationLabel = uilabel(app.UIFigure);
            app.TimecalibrationLabel.Text = 'Time calibration';
            app.CurrentexperimenttimeminuteEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentexperimenttimeminuteEditFieldLabel.Text = 'Current experiment time:              minute';
            app.CurrentexperimenttimeminuteEditField = uieditfield(app.UIFigure, 'numeric');
            app.WarningonceyoustartreadingsyouwillnotbeLabel = uilabel(app.UIFigure);
            app.WarningonceyoustartreadingsyouwillnotbeLabel.Text = {'Warning: once you start readings, you will not be'; 'able to calibrate time again.'};
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = window_timecalibration(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end